classdef SpectralMeasurements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralMeasurement            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ProjectMenu                    matlab.ui.container.Menu
        ProjectSave                    matlab.ui.container.Menu
        ProjectOpen                    matlab.ui.container.Menu
        ProjectNew                     matlab.ui.container.Menu
        SweepMenu                      matlab.ui.container.Menu
        SweepSave                      matlab.ui.container.Menu
        SweepOpen                      matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        EffectiveIndexCalculationMenu  matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SpectrumTab                    matlab.ui.container.Tab
        UIAxes01                       matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        BTab                           matlab.ui.container.Tab
        LaserControlPanel              matlab.ui.container.Panel
        nmLabel                        matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        WavelengthSpinnerLabel         matlab.ui.control.Label
        txtWavelength                  matlab.ui.control.Spinner
        OutputPowerSpinnerLabel        matlab.ui.control.Label
        txtPower                       matlab.ui.control.Spinner
        btnLaserOn                     matlab.ui.control.StateButton
        ConnectionPanel                matlab.ui.container.Panel
        instrConnStat                  matlab.ui.control.Lamp
        InstrumentDropDownLabel        matlab.ui.control.Label
        InstrSelect1                   matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        LaserOutputLampLabel           matlab.ui.control.Label
        LaserOutputLamp                matlab.ui.control.Lamp
        PowerSensorPanel               matlab.ui.container.Panel
        pwrMeas1                       matlab.ui.control.Label
        dBmLabel_3                     matlab.ui.control.Label
        btnPowerScan                   matlab.ui.control.StateButton
        AveragingTSpinnerLabel         matlab.ui.control.Label
        AveragingTSpinner              matlab.ui.control.Spinner
        RangeDropDownLabel             matlab.ui.control.Label
        RangeDropDown                  matlab.ui.control.DropDown
        msLabel                        matlab.ui.control.Label
        AutoCheckBox                   matlab.ui.control.CheckBox
        OpticalSwitchPanel             matlab.ui.container.Panel
        SwitchAKnobLabel               matlab.ui.control.Label
        SwitchAKnob                    matlab.ui.control.DiscreteKnob
        SwitchBKnobLabel               matlab.ui.control.Label
        SwitchBKnob                    matlab.ui.control.DiscreteKnob
        LightwaveMeasurementSystemLabel  matlab.ui.control.Label
        SweepTab                       matlab.ui.container.Tab
        ScanParametersPanel            matlab.ui.container.Panel
        nmLabel_2                      matlab.ui.control.Label
        nmLabel_3                      matlab.ui.control.Label
        btnRunSweep                    matlab.ui.control.StateButton
        ScanStartSpinnerLabel          matlab.ui.control.Label
        spinStart                      matlab.ui.control.Spinner
        ScanStopSpinnerLabel           matlab.ui.control.Label
        spinStop                       matlab.ui.control.Spinner
        StepSizeSpinnerLabel           matlab.ui.control.Label
        spinStep                       matlab.ui.control.Spinner
        nmLabel_4                      matlab.ui.control.Label
        ScanSpeedLabel                 matlab.ui.control.Label
        spinSpeed                      matlab.ui.control.Spinner
        nmsLabel                       matlab.ui.control.Label
        RangeLabel                     matlab.ui.control.Label
        RangeDropDown_Sweep            matlab.ui.control.DropDown
        DataAnalysisTab                matlab.ui.container.Tab
        Peakfinder2GreenPanel          matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        HeightSpinner_2Label           matlab.ui.control.Label
        HeightSpinner_2                matlab.ui.control.Spinner
        ProminenceSpinner_2Label       matlab.ui.control.Label
        ProminenceSpinner_2            matlab.ui.control.Spinner
        MaxWidthSpinner_2Label         matlab.ui.control.Label
        MaxWidthSpinner_2              matlab.ui.control.Spinner
        MinWidthSpinner_2Label         matlab.ui.control.Label
        MinWidthSpinner_2              matlab.ui.control.Spinner
        PeakSpacingLabel_2             matlab.ui.control.Label
        DistanceSpinner_2              matlab.ui.control.Spinner
        dBmLabel_5                     matlab.ui.control.Label
        nmLabel_8                      matlab.ui.control.Label
        nmLabel_9                      matlab.ui.control.Label
        nmLabel_10                     matlab.ui.control.Label
        MinPeakHeightCheckBox_2        matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox_2    matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox_2      matlab.ui.control.CheckBox
        InterferenceAnalysisPanel      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        TypeofdeviceDropDownLabel      matlab.ui.control.Label
        TypeofdeviceDropDown           matlab.ui.control.DropDown
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        GridLayout5                    matlab.ui.container.GridLayout
        neffectiveEditFieldLabel       matlab.ui.control.Label
        neffectiveEditField            matlab.ui.control.NumericEditField
        FindPeaksButton                matlab.ui.control.Button
        Peakfinder1RedPanel            matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        HeightLabel                    matlab.ui.control.Label
        HeightSpinner                  matlab.ui.control.Spinner
        ProminenceLabel                matlab.ui.control.Label
        ProminenceSpinner              matlab.ui.control.Spinner
        MaxWidthLabel                  matlab.ui.control.Label
        MaxWidthSpinner                matlab.ui.control.Spinner
        MinWidthLabel                  matlab.ui.control.Label
        MinWidthSpinner                matlab.ui.control.Spinner
        PeakSpacingLabel               matlab.ui.control.Label
        DistanceSpinner                matlab.ui.control.Spinner
        dBmLabel_4                     matlab.ui.control.Label
        nmLabel_5                      matlab.ui.control.Label
        nmLabel_6                      matlab.ui.control.Label
        nmLabel_7                      matlab.ui.control.Label
        MinPeakHeightCheckBox          matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox      matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox        matlab.ui.control.CheckBox
        UIAxes01_2                     matlab.ui.control.UIAxes
        ConfigurationTab               matlab.ui.container.Tab
        UIComponentsTable              matlab.ui.control.Table
        SaveButton                     matlab.ui.control.Button
        logger                         matlab.ui.control.TextArea
        UITable2                       matlab.ui.control.Table
        HoldPlotCheckBox               matlab.ui.control.CheckBox
        ResonanceAnalysisLabel         matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        ProjectPanel                   matlab.ui.container.Panel
        tblMeasurements                matlab.ui.control.Table
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        connection                 
    end
    
    properties (Access = private)
        Timer % Description
        connPanel01 % Description
        LockLaser % Description
        saveFolder % Description
        mData % Description
        
        sweep % Structure with members {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
    end
    
    methods (Access = private)
        
        function [status, idn] = sm_connect(app,g)
            status = 0; idn = 0;
            try
                if strcmp(g.status,'open'), fclose(g); end %Force to reset the connection.
                fopen(g); 
                fprintf(g,'*IDN?');
                idn = fscanf(g);
                status = 1;
                fclose(g); 
            catch EM
                log(app,EM.message)
                
            end
        end
        
        function TimerFcn(app, src, event)
        %put on a try and add switch off
            if strcmp(app.instrConnStat.Enable,'on')
                gpib_conn = app.connPanel01.gpib;
                if strcmp(gpib_conn.status,'open') 
                    fprintf(gpib_conn,'read1:pow?');
                    val = str2num(fscanf(gpib_conn));
                    app.pwrMeas1.Text = num2str(val,'%.4f');
                end
            end
        end
        
        function laserStat = switchLaser(app,g, laserStat)
            %Check if the laser is locked and unlock in necessary
            app.LockLaser = false;
            fprintf(g,'lock?');lockStat = str2double(fscanf(g));
            if lockStat
                fprintf(g,'lock 0,1234');
                app.LockLaser = true;
                log(app,'Unlocking the laser using the default password.')
                fprintf(g,'lock?'); lockStat = str2double(fscanf(g));
                if ~lockStat, log(app,'Laser unlocked.');
                else, log(app,'Unabled to unlock the laser.');
                end
            end
                
            %Switch the laser
            fprintf(g,"outp "+laserStat);
            fprintf(g,"outp?");laserStat = str2num(fscanf(g));
            app.LaserOutputLamp.Enable = laserStat;
            
            %Lock the laser if the initial state was locked
            if ~laserStat && app.LockLaser, fprintf(g,'lock 1,1234');end
        end
        
        function wav = setLaserWav(app,g, wav)
            %Sets the laser01 central wavelenght and returns the
            %configured value in nm

            fprintf(g,"sour0:chan1:wav " + num2str(wav,'%5.3f')+'nm');
            fprintf(g,'sour0:chan1:wav?');wav = str2num(fscanf(g))*1e9;
        end
        
        function pow = setLaserPower(app,g,pow)
            fprintf(g,"sour0:chan1:pow " + num2str(pow,'%5.0f'));
            fprintf(g,'sour0:chan1:pow?');pow = str2num(fscanf(g));
        end
        
        function enableControls(app, value)
            app.instrConnStat.Enable = value;                      
            app.SwitchAKnob.Enable = value;
            app.btnRunSweep.Enable = value;
            app.btnPowerScan.Enable = value;
            app.btnLaserOn.Enable = value;
        end
        
        function setPowerScan(app,value)
            if value
                try  
                    fopen(app.connPanel01.gpib);
                    fprintf(app.connPanel01.gpib,'*CLS');
                    fprintf(app.connPanel01.gpib,'*OPC?'); 
                    opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        range = app.RangeDropDown.Value;
                        avgT = app.AveragingTSpinner.Value*1e-3;
                        fprintf(app.connPanel01.gpib,"sens1:pow:atim "+ num2str(avgT,'%5.2f') + 's');
                        fprintf(app.connPanel01.gpib,"sens1:pow:atim?"); avgT =  str2num(fscanf(app.connPanel01.gpib));
                        if ~app.AutoCheckBox.Value
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang:auto 0");
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang "+ range);
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang?"); range =  str2num(fscanf(app.connPanel01.gpib));
                            log(app,"Configured sensor with averaging time: " + avgT + " and range " + num2str(range,'%5.2f') +' dBm.');
                        else
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang:auto 1");
                            log(app,"Configured sensor with averaging time: "+avgT+" and automatic range.")
                        end
                        
                        start(app.Timer);
                    else
                        app.Switch.Value = false;
                        fclose(app.connPanel01.gpib);
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    stop(app.Timer);
                    app.btnPowerScan.Value = false;
                    if ~isempty(app.connPanel01.gpib), fclose(app.connPanel01.gpib);end
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            else
                stop(app.Timer);
                fclose(app.connPanel01.gpib);
            end
        end
        
        function results = processData(app)
            data = app.sweep.spectra;
            filterStat{1} = [ app.MinPeakHeightCheckBox.Value,...
                        app.MinPeakProminenceCheckBox.Value,...
                        app.MaxPeakWidthCheckBox.Value,...
                        app.MinPeakWidthCheckBox.Value,...
                        app.MinPeakDistanceCheckBox.Value];
    
            filterStat{2} = [ app.MinPeakHeightCheckBox_2.Value,...
                        app.MinPeakProminenceCheckBox_2.Value,...
                        app.MaxPeakWidthCheckBox_2.Value,...
                        app.MinPeakWidthCheckBox_2.Value,...
                        app.MinPeakDistanceCheckBox_2.Value];
            
            filterParam{1} = [app.HeightSpinner.Value,...
                        app.ProminenceSpinner.Value,...
                        app.MaxWidthSpinner.Value,...
                        app.MinWidthSpinner.Value,...
                        app.DistanceSpinner.Value
                        ];
                    
            filterParam{2} = [app.HeightSpinner_2.Value,...
                        app.ProminenceSpinner_2.Value,...
                        app.MaxWidthSpinner_2.Value,...
                        app.MinWidthSpinner_2.Value,...
                        app.DistanceSpinner_2.Value
                        ];
            
            
            ax1 = app.UIAxes01;            
            [pks,wav,FSR]=getPeaks(data,filterStat,filterParam);
            if ~isempty(pks) 
                if app.HoldPlotCheckBox.Value
                    hold(app.UIAxes01,'on');
                end
                plot (ax1,data(:,1),data(:,2), wav{1}, pks{1},'V','MarkerFaceColor','red'); 
                hold(app.UIAxes01,'off');
                
                xlabels = cellstr(num2str(wav{1}*1e9));
                lbls={};
                for i =1:length(wav{1}),lbls{i}=xlabels{i};end
                text(ax1,wav{1}, pks{1},lbls);
            else
                 plot (ax1,data(:,1),data(:,2));
            end
            
            
        end
    end
    
    methods (Access = public)
        
        function results = log(app,logText)
            oldlogText = app.logger.Value;
            maxLines = 100;
            if length(oldlogText)<maxLines
                logText = [logText; oldlogText];
            else
                logText = [logText;oldlogText(1:maxLines-1)];
            end
            app.logger.Value = logText;
        end
        
        
        function setWorkspace(app, configData)
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.UIComponentsTable.Data = configData;
            
            %Fill the combo boxes with the instruments available in the
            %configuration table
            comboStrings = strcat(string(configData.ID),'-',configData.Name);
            comboValues = configData.ID;
            app.InstrSelect1.Items = comboStrings;
            app.InstrSelect1.ItemsData = comboValues;
            
            
            %Define the GPIB connections
            app.connection = [];
            for i =1:size(configData,1)
                c = configData(i,:);
                app.connection(i).config = c;
                app.connection(i).ID = string(c.ID);
                app.connection(i).gpib = gpib(string(c.vendor), c.Board, c.GPIB);
                app.connection(i).status = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Name = ["Empty"];
            %Description = [""];
            %GPIB = [0];
            %Timeout = [30];
            %Enabled = [true];
            %            t = table(Name, Description, GPIB, Timeout, Enabled);
            
            %Clear any existing instruments
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end
            
            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
           
            app.Timer = timer('Period',0.3,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;
            
            setWorkspace(app, configData)
            app.saveFolder = "C:\Users\Public\Documents\Shared Data\Users\";
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            instrID = string(app.InstrSelect1.Value);
            t = app.UIComponentsTable.Data;
            status = false;
            
            for connIndex = 1:length(app.connection)
                c = app.connection(connIndex);
                g = c.gpib;
                if strcmp(c.ID,instrID)
                    if t(instrID,:).Enabled
                        
                        [status, idn] = sm_connect(app,g);
                        if ~status
                            %try to restart the connection
                            config = c.config;
                            c.gpib = gpib(string(config.vendor), config.Board, config.GPIB);
                            [status, idn] = sm_connect(app,g);
                        end
                        
                        if status
                            log(app,"Connected to "+idn);
                            app.instrConnStat.Enable='on';
                            
                            switch_slot = 4;
                            fopen(c.gpib);
                            swStat = send(c.gpib,"rout"+switch_slot+":chan1?");
                            
                            app.SwitchAKnob.Value = swStat(3);
                            app.SwitchAKnob.Enable = true;
                            app.btnRunSweep.Enable = true;
                            app.btnPowerScan.Enable = true;
                            app.btnLaserOn.Enable = true;
                            c.status = true;
                            app.connPanel01 = c;    %set the connection for the 1st panel as the on e found for the selected ID.
                            
                            fclose(c.gpib);
                        else
                            log(app,"The selected instrument does not respond.")
                            try switchLaser(app,g, 0); %% ---------------------------Test
                            catch EM
                            end
                            c.status = false;
                            app.connPanel01 = c;
                        end
                    else
                        log(app,"The selected instrument is not enabled in the configuration.")
                       
                    end
                    break
                end
            end
            
            if (~status)
                log(app,"Failed to connect to the measurement system.")
            end
        end

        % Value changed function: btnPowerScan
        function btnPowerScanValueChanged(app, event)
            %This function will open the COM with the instrument and leave
            %it open for increase performance, and start the timed function
            %to query for the measurements in the Power Sensor.
            value = app.btnPowerScan.Value;
            setPowerScan(app, value);
            
        end

        % Value changed function: btnLaserOn
        function btnLaserOnValueChanged(app, event)
            value = double(app.btnLaserOn.Value);
            closeConn = false;
            try  
                if strcmp(app.connPanel01.gpib.status,'closed')
                    closeConn = true;
                    fopen(app.connPanel01.gpib);
                end
                
                %Check if the intsrument can receive commands (this could
                %be on a while to wait until the instrument is ready).
                fprintf(app.connPanel01.gpib,'*OPC?'); 
                opcStat = str2double(fscanf(app.connPanel01.gpib));
                if opcStat
                    laserStat = switchLaser(app,app.connPanel01.gpib,value);
                    if laserStat
                        dblWav = setLaserWav(app,app.connPanel01.gpib, app.txtWavelength.Value);
                        dblPow = setLaserPower(app,app.connPanel01.gpib, app.txtPower.Value);
                        
                        app.txtWavelength.Value = dblWav;
                        app.txtPower.Value= dblPow;
                    end
                    

                else
                    app.btnLaserOn.Value = false;
                    log(app,"The instrument is not ready.")
                end
            catch ME
                app.btnLaserOn.Value = false;
                log(app,"Error: "+ME.message);
                log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            if closeConn, fclose(app.connPanel01.gpib);end


        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if value
                app.RangeDropDown.Enable = false;
            else
                app.RangeDropDown.Enable = true;
            end
        end

        % Value changed function: txtWavelength
        function txtWavelengthValueChanged(app, event)
            laserStat = double(app.btnLaserOn.Value);
            if laserStat
                closeConn = false;
                try  
                    if strcmp(app.connPanel01.gpib.status,'closed') %Use this in other parts of the code as well to check for the connection status
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the instrument can receive commands
                    
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblWav = app.txtWavelength.Value;
                        if laserStat, dblWav = setLaserWav(app,app.connPanel01.gpib, dblWav); end
                        app.txtWavelength.Value = dblWav;
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end
        end

        % Close request function: SpectralMeasurement
        function SpectralMeasurementCloseRequest(app, event)
            %Make sure to close all connections and delete the objects
            
            for i = 1:length(app.connection)
                try 
                    fclose(app.connection(i).gpib);
                catch EM
                end
                delete(app.connection(i).gpib);
                
            end
            delete(app)
            clear app
        end

        % Value changed function: SwitchAKnob
        function SwitchAKnobValueChanged(app, event)
            if app.connPanel01.status   
                value = app.SwitchAKnob.Value;
                closeConn = false;
                try  
                    g = app.connPanel01.gpib;
                    if strcmp(g.status,'closed')
                        closeConn = true;
                        fopen(g);
                    end
                    
                    %Check if the intsrument can receive commands
                    fprintf(g,'*OPC?'); 
                    if str2double(fscanf(g))
                        fprintf(g,"rout4:chan1 A,"+value);
                        fprintf(g,"rout4:chan1?"); swStat = fscanf(g);
                        app.SwitchAKnob.Value = swStat(3);  %This works only for single switches
                        
                    else
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(g);end
            end
        end

        % Value changed function: btnRunSweep
        function btnRunSweepValueChanged(app, event)
            value = app.btnRunSweep.Value;
            if value
                stop(app.Timer);
                app.btnPowerScan.Value = 0;
                app.btnLaserOn.Value = 0;
                opcStat = 0;
                g = app.connPanel01.gpib;
                if app.connPanel01.status
                    try fopen(g); catch ME, log(app,ME.message); end
                    
                    send(g,'*CLS');
                    opcStat = str2double(send(g,'*OPC?'));
                    
                    if opcStat
                        enableControls(app, 0);
                        app.sweep.parameters.channel = 1;
                        app.sweep.parameters.power = 3;             % Power in dBm (0dBm = 1mW)  -- Change to user input
                        app.sweep.parameters.starWav =  app.spinStart.Value*1e-9;  % start wavelength in m
                        app.sweep.parameters.stopWav =  app.spinStop.Value*1e-9;   % stop wavelength in m
                        app.sweep.parameters.step =     app.spinStep.Value*1e-9;   % stepsize in meters
                        app.sweep.parameters.sweepSpeed = app.spinSpeed.Value*1e-9;  % sweep speed in m/s
                        app.sweep.parameters.range = app.RangeDropDown_Sweep.Value;  % range in dBm in steps of 10, between [-70 and 40];
                          
                        %park it at the start wavelength and turn off laser 
                        dblWav = setLaserWav(app,g, app.sweep.parameters.starWav*1e9);
                        pause(2)
                        switchLaser(app,g, 0);
                        
                        % Prepare scan
                        [app.sweep.parameters,sweepP] = prepareScan(g,app.sweep.parameters);
                        if sweepP.stat
                            app.sweep.spectra = runSweep(g,app.sweep.parameters);
                            processData(app);
                        end
                        enableControls(app, 1);
                    end
                    fclose(g);
                end
            end
            app.btnRunSweep.Value = 0;
            enableControls(app, 1);
        end

        % Value changed function: txtPower
        function txtPowerValueChanged(app, event)

            if app.connPanel01.status
                
                try  
                    closeConn = false;
                    if strcmp(app.connPanel01.gpib.status,'closed')
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the ind=strument can receive commands
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblPow = app.txtPower.Value;
                        app.txtPower.Value = setLaserPower(app,app.connPanel01.gpib, dblPow);
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end      
        end

        % Value changed function: RangeDropDown
        function RangeDropDownValueChanged(app, event)
            value = app.RangeDropDown.Value;
            
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            processData(app);
        end

        % Menu selected function: EffectiveIndexCalculationMenu
        function modeCalculatorSelected(app, event)
            ModeCalculator;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectralMeasurement.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {824, 824};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1067, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SaveButton
        function ConfigSavePushed(app, event)
            configData = app.UIComponentsTable.Data;
            save('config.mat', 'configData','-mat');
        end

        % Menu selected function: ProjectOpen
        function ProjectOpenSelected(app, event)
            
        end

        % Menu selected function: ProjectSave
        function ProjectSaveSelected(app, event)
            
        end

        % Menu selected function: ProjectNew
        function ProjectNewSelected(app, event)
            
        end

        % Menu selected function: SweepSave
        function SweepSaveSelected(app, event)
            data = app.mData;
            file = datestr(now,'ddmmyyyy')+"_alldata.mat";
            path = app.saveFolder;
            
            [file,path] = uiputfile(strcat(path,file),'Save sweep data as');
            if path~=0
               
                save(strcat(path,file),'data');
                app.saveFolder = path;
            end
           figure(app.SpectralMeasurement);
        end

        % Menu selected function: OpenMenu, SweepOpen
        function SweepOpenSelected(app, event)
            %try
                path = app.saveFolder;
                [file,path] = uigetfile(strcat(path,"*.mat"),'MultiSelect', 'on');
                
                if ~isempty(path)
                    
                    if path ~= 0
                        if ischar(file), files{1} = file;
                        else, files = file; end
                        
                        for i = 1:length(files)
                            data = load(strcat(path,files{i}));
                            app.sweep.spectra = data.data;
                            processData(app);
                            app.saveFolder = path;
                            
                            mData(:,:,i) =  data.data; 
                        end
                        app.mData = mData;
                    end
                end
                figure(app.SpectralMeasurement);
%             catch EM
%                 log(app,EM.message)
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralMeasurement = uifigure('Visible', 'off');
            app.SpectralMeasurement.Name = 'Spectral Measurements';
            app.SpectralMeasurement.CloseRequestFcn = createCallbackFcn(app, @SpectralMeasurementCloseRequest, true);
            app.SpectralMeasurement.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.SpectralMeasurement);
            app.FileMenu.Text = 'File';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project';
            app.ProjectSave = uimenu(app.ProjectMenu);
            app.ProjectSave.MenuSelectedFcn = createCallbackFcn(app, @ProjectSaveSelected, true);
            app.ProjectSave.Text = 'Save';
            app.ProjectOpen = uimenu(app.ProjectMenu);
            app.ProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @ProjectOpenSelected, true);
            app.ProjectOpen.Text = 'Open';
            app.ProjectNew = uimenu(app.ProjectMenu);
            app.ProjectNew.MenuSelectedFcn = createCallbackFcn(app, @ProjectNewSelected, true);
            app.ProjectNew.Text = 'New';
            app.SweepMenu = uimenu(app.FileMenu);
            app.SweepMenu.Text = 'Sweep';
            app.SweepSave = uimenu(app.SweepMenu);
            app.SweepSave.MenuSelectedFcn = createCallbackFcn(app, @SweepSaveSelected, true);
            app.SweepSave.Text = 'Save';
            app.SweepOpen = uimenu(app.SweepMenu);
            app.SweepOpen.MenuSelectedFcn = createCallbackFcn(app, @SweepOpenSelected, true);
            app.SweepOpen.Text = 'Open';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @SweepOpenSelected, true);
            app.OpenMenu.Text = 'Open';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.Text = 'Menu';
            app.ToolsMenu = uimenu(app.SpectralMeasurement);
            app.ToolsMenu.Text = 'Tools';
            app.EffectiveIndexCalculationMenu = uimenu(app.ToolsMenu);
            app.EffectiveIndexCalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @modeCalculatorSelected, true);
            app.EffectiveIndexCalculationMenu.Text = 'Effective Index Calculation';
            app.GridLayout = uigridlayout(app.SpectralMeasurement);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.UIAxes01 = uiaxes(app.SpectrumTab);
            title(app.UIAxes01, 'Spectrum')
            xlabel(app.UIAxes01, 'wavelength (m)')
            ylabel(app.UIAxes01, 'intensity (dBm)')
            app.TabGroup2 = uitabgroup(app.SpectrumTab);
            app.BTab = uitab(app.TabGroup2);
            app.BTab.Title = '8164B';
            app.LaserControlPanel = uipanel(app.BTab);
            app.LaserControlPanel.Title = 'Laser Control';
            app.nmLabel = uilabel(app.LaserControlPanel);
            app.nmLabel.Text = 'nm';
            app.dBmLabel = uilabel(app.LaserControlPanel);
            app.dBmLabel.Text = 'dBm';
            app.WavelengthSpinnerLabel = uilabel(app.LaserControlPanel);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.txtWavelength = uispinner(app.LaserControlPanel);
            app.txtWavelength.Step = 0.1;
            app.txtWavelength.Limits = [1500 1640];
            app.txtWavelength.ValueDisplayFormat = '%.3f';
            app.txtWavelength.ValueChangedFcn = createCallbackFcn(app, @txtWavelengthValueChanged, true);
            app.txtWavelength.Value = 1550;
            app.OutputPowerSpinnerLabel = uilabel(app.LaserControlPanel);
            app.OutputPowerSpinnerLabel.Text = 'Output Power';
            app.txtPower = uispinner(app.LaserControlPanel);
            app.txtPower.Step = 0.1;
            app.txtPower.Limits = [0 13];
            app.txtPower.ValueDisplayFormat = '%5.2f';
            app.txtPower.ValueChangedFcn = createCallbackFcn(app, @txtPowerValueChanged, true);
            app.txtPower.Value = 3;
            app.btnLaserOn = uibutton(app.LaserControlPanel, 'state');
            app.btnLaserOn.ValueChangedFcn = createCallbackFcn(app, @btnLaserOnValueChanged, true);
            app.btnLaserOn.Enable = 'off';
            app.btnLaserOn.Text = 'On';
            app.ConnectionPanel = uipanel(app.BTab);
            app.ConnectionPanel.Title = 'Connection';
            app.instrConnStat = uilamp(app.ConnectionPanel);
            app.instrConnStat.Enable = 'off';
            app.InstrumentDropDownLabel = uilabel(app.ConnectionPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrSelect1 = uidropdown(app.ConnectionPanel);
            app.InstrSelect1.ItemsData = {'1001'};
            app.InstrSelect1.Value = '1001';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LaserOutputLampLabel = uilabel(app.ConnectionPanel);
            app.LaserOutputLampLabel.Text = 'Laser Output';
            app.LaserOutputLamp = uilamp(app.ConnectionPanel);
            app.LaserOutputLamp.Enable = 'off';
            app.PowerSensorPanel = uipanel(app.BTab);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.pwrMeas1 = uilabel(app.PowerSensorPanel);
            app.pwrMeas1.Text = '0.00';
            app.dBmLabel_3 = uilabel(app.PowerSensorPanel);
            app.dBmLabel_3.Text = 'dBm';
            app.btnPowerScan = uibutton(app.PowerSensorPanel, 'state');
            app.btnPowerScan.ValueChangedFcn = createCallbackFcn(app, @btnPowerScanValueChanged, true);
            app.btnPowerScan.Enable = 'off';
            app.btnPowerScan.Text = 'Run';
            app.AveragingTSpinnerLabel = uilabel(app.PowerSensorPanel);
            app.AveragingTSpinnerLabel.Text = 'Averaging T';
            app.AveragingTSpinner = uispinner(app.PowerSensorPanel);
            app.AveragingTSpinner.Limits = [1 1000];
            app.AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.AveragingTSpinner.Value = 200;
            app.RangeDropDownLabel = uilabel(app.PowerSensorPanel);
            app.RangeDropDownLabel.Enable = 'off';
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.PowerSensorPanel);
            app.RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeDropDownValueChanged, true);
            app.RangeDropDown.Enable = 'off';
            app.RangeDropDown.Value = '-20DBM';
            app.msLabel = uilabel(app.PowerSensorPanel);
            app.msLabel.Text = 'ms';
            app.AutoCheckBox = uicheckbox(app.PowerSensorPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.OpticalSwitchPanel = uipanel(app.BTab);
            app.OpticalSwitchPanel.Title = 'Optical Switch';
            app.SwitchAKnobLabel = uilabel(app.OpticalSwitchPanel);
            app.SwitchAKnobLabel.Enable = 'off';
            app.SwitchAKnobLabel.Text = 'Switch A';
            app.SwitchAKnob = uiknob(app.OpticalSwitchPanel, 'discrete');
            app.SwitchAKnob.Items = {'1', '2', '3', '4'};
            app.SwitchAKnob.ValueChangedFcn = createCallbackFcn(app, @SwitchAKnobValueChanged, true);
            app.SwitchAKnob.Enable = 'off';
            app.SwitchAKnob.Value = '1';
            app.SwitchBKnobLabel = uilabel(app.OpticalSwitchPanel);
            app.SwitchBKnobLabel.Enable = 'off';
            app.SwitchBKnobLabel.Text = 'Switch B';
            app.SwitchBKnob = uiknob(app.OpticalSwitchPanel, 'discrete');
            app.SwitchBKnob.Items = {'1', '2', '3', '4'};
            app.SwitchBKnob.Enable = 'off';
            app.SwitchBKnob.Value = '1';
            app.LightwaveMeasurementSystemLabel = uilabel(app.BTab);
            app.LightwaveMeasurementSystemLabel.Text = 'Lightwave Measurement System';
            app.SweepTab = uitab(app.TabGroup2);
            app.SweepTab.Title = 'Sweep';
            app.ScanParametersPanel = uipanel(app.SweepTab);
            app.ScanParametersPanel.Title = 'Scan Parameters';
            app.nmLabel_2 = uilabel(app.ScanParametersPanel);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.ScanParametersPanel);
            app.nmLabel_3.Text = 'nm';
            app.btnRunSweep = uibutton(app.ScanParametersPanel, 'state');
            app.btnRunSweep.ValueChangedFcn = createCallbackFcn(app, @btnRunSweepValueChanged, true);
            app.btnRunSweep.Enable = 'off';
            app.btnRunSweep.Text = 'Run';
            app.ScanStartSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.ScanParametersPanel);
            app.spinStart.Limits = [1500 1640];
            app.spinStart.Value = 1520;
            app.ScanStopSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.ScanParametersPanel);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1580;
            app.StepSizeSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.ScanParametersPanel);
            app.spinStep.Step = 0.01;
            app.spinStep.Limits = [0.01 1];
            app.spinStep.Value = 0.1;
            app.nmLabel_4 = uilabel(app.ScanParametersPanel);
            app.nmLabel_4.Text = 'nm';
            app.ScanSpeedLabel = uilabel(app.ScanParametersPanel);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uispinner(app.ScanParametersPanel);
            app.spinSpeed.Step = 0.1;
            app.spinSpeed.Limits = [0.1 10];
            app.spinSpeed.ValueDisplayFormat = '%2.1f';
            app.spinSpeed.Value = 1;
            app.nmsLabel = uilabel(app.ScanParametersPanel);
            app.nmsLabel.Text = 'nm/s';
            app.RangeLabel = uilabel(app.ScanParametersPanel);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown_Sweep = uidropdown(app.ScanParametersPanel);
            app.RangeDropDown_Sweep.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown_Sweep.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.RangeDropDown_Sweep.Value = '10';
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.Peakfinder2GreenPanel = uipanel(app.DataAnalysisTab);
            app.Peakfinder2GreenPanel.Title = 'Peak finder 2 (Green)';
            app.GridLayout6 = uigridlayout(app.Peakfinder2GreenPanel);
            app.HeightSpinner_2Label = uilabel(app.GridLayout6);
            app.HeightSpinner_2Label.Text = 'Height';
            app.HeightSpinner_2 = uispinner(app.GridLayout6);
            app.HeightSpinner_2.Value = -35;
            app.ProminenceSpinner_2Label = uilabel(app.GridLayout6);
            app.ProminenceSpinner_2Label.Text = 'Prominence';
            app.ProminenceSpinner_2 = uispinner(app.GridLayout6);
            app.ProminenceSpinner_2.Value = 15;
            app.MaxWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MaxWidthSpinner_2Label.Text = 'Max Width';
            app.MaxWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MinWidthSpinner_2Label.Text = 'Min Width';
            app.MinWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2.Value = 0.5;
            app.PeakSpacingLabel_2 = uilabel(app.GridLayout6);
            app.PeakSpacingLabel_2.Text = 'Peak Spacing';
            app.DistanceSpinner_2 = uispinner(app.GridLayout6);
            app.DistanceSpinner_2.Value = 3;
            app.dBmLabel_5 = uilabel(app.GridLayout6);
            app.dBmLabel_5.Text = 'dBm';
            app.nmLabel_8 = uilabel(app.GridLayout6);
            app.nmLabel_8.Text = 'nm';
            app.nmLabel_9 = uilabel(app.GridLayout6);
            app.nmLabel_9.Text = 'nm';
            app.nmLabel_10 = uilabel(app.GridLayout6);
            app.nmLabel_10.Text = 'nm';
            app.MinPeakHeightCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakHeightCheckBox_2.Text = '';
            app.MinPeakProminenceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakProminenceCheckBox_2.Text = '';
            app.MaxPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MaxPeakWidthCheckBox_2.Text = '';
            app.MinPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakWidthCheckBox_2.Text = '';
            app.MinPeakDistanceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakDistanceCheckBox_2.Text = '';
            app.InterferenceAnalysisPanel = uipanel(app.DataAnalysisTab);
            app.InterferenceAnalysisPanel.Title = 'Interference Analysis';
            app.GridLayout3 = uigridlayout(app.InterferenceAnalysisPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TypeofdeviceDropDownLabel = uilabel(app.GridLayout4);
            app.TypeofdeviceDropDownLabel.Text = 'Type of device';
            app.TypeofdeviceDropDown = uidropdown(app.GridLayout4);
            app.TypeofdeviceDropDown.Items = {'Resonator', 'Interferometer', 'Other'};
            app.TypeofdeviceDropDown.Value = 'Resonator';
            app.dEditFieldLabel = uilabel(app.GridLayout4);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.neffectiveEditFieldLabel = uilabel(app.GridLayout5);
            app.neffectiveEditFieldLabel.Text = 'n effective ';
            app.neffectiveEditField = uieditfield(app.GridLayout5, 'numeric');
            app.neffectiveEditField.Limits = [1 50];
            app.neffectiveEditField.Value = 3.4;
            app.FindPeaksButton = uibutton(app.GridLayout5, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.Peakfinder1RedPanel = uipanel(app.DataAnalysisTab);
            app.Peakfinder1RedPanel.Title = 'Peak finder 1 (Red)';
            app.GridLayout7 = uigridlayout(app.Peakfinder1RedPanel);
            app.HeightLabel = uilabel(app.GridLayout7);
            app.HeightLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout7);
            app.HeightSpinner.Value = -35;
            app.ProminenceLabel = uilabel(app.GridLayout7);
            app.ProminenceLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.GridLayout7);
            app.ProminenceSpinner.Value = 15;
            app.MaxWidthLabel = uilabel(app.GridLayout7);
            app.MaxWidthLabel.Text = 'Max Width';
            app.MaxWidthSpinner = uispinner(app.GridLayout7);
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthLabel = uilabel(app.GridLayout7);
            app.MinWidthLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.GridLayout7);
            app.PeakSpacingLabel = uilabel(app.GridLayout7);
            app.PeakSpacingLabel.Text = 'Peak Spacing';
            app.DistanceSpinner = uispinner(app.GridLayout7);
            app.DistanceSpinner.Value = 3;
            app.dBmLabel_4 = uilabel(app.GridLayout7);
            app.dBmLabel_4.Text = 'dBm';
            app.nmLabel_5 = uilabel(app.GridLayout7);
            app.nmLabel_5.Text = 'nm';
            app.nmLabel_6 = uilabel(app.GridLayout7);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.GridLayout7);
            app.nmLabel_7.Text = 'nm';
            app.MinPeakHeightCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakHeightCheckBox.Text = '';
            app.MinPeakProminenceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakProminenceCheckBox.Text = '';
            app.MinPeakProminenceCheckBox.Value = true;
            app.MaxPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MaxPeakWidthCheckBox.Text = '';
            app.MaxPeakWidthCheckBox.Value = true;
            app.MinPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakWidthCheckBox.Text = '';
            app.MinPeakDistanceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakDistanceCheckBox.Text = '';
            app.UIAxes01_2 = uiaxes(app.DataAnalysisTab);
            title(app.UIAxes01_2, 'Spectrum')
            xlabel(app.UIAxes01_2, 'wavelength (m)')
            ylabel(app.UIAxes01_2, 'intensity (dBm)')
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UIComponentsTable = uitable(app.ConfigurationTab);
            app.UIComponentsTable.ColumnName = {'ID'; 'Name'; 'Type'; 'Description'; 'Vendor'; 'Board'; 'GPIB Port'; 'Timeout'; 'Enabled'};
            app.UIComponentsTable.RowName = {};
            app.UIComponentsTable.ColumnEditable = true;
            app.SaveButton = uibutton(app.ConfigurationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigSavePushed, true);
            app.SaveButton.Text = 'Save';
            app.logger = uitextarea(app.LeftPanel);
            app.logger.Value = {'Spectral Measurements 1.1  (C) NYU PRL 2019'};
            app.UITable2 = uitable(app.LeftPanel);
            app.UITable2.ColumnName = {'Peak'; 'Wavelength'; 'FSR'; 'FWHM'; 'Q. Factor'; 'Group Index'; 'Loss Coeficient'};
            app.UITable2.RowName = {};
            app.HoldPlotCheckBox = uicheckbox(app.LeftPanel);
            app.HoldPlotCheckBox.Text = 'Hold Plot';
            app.ResonanceAnalysisLabel = uilabel(app.LeftPanel);
            app.ResonanceAnalysisLabel.Text = 'Resonance Analysis';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'tandon_long_color.png';
            app.ProjectPanel = uipanel(app.RightPanel);
            app.ProjectPanel.Title = 'Project';
            app.tblMeasurements = uitable(app.ProjectPanel);
            app.tblMeasurements.ColumnName = {'Time'; 'Var 1'; 'Var 2'; 'Var 3'};
            app.tblMeasurements.RowName = {};
            app.NameEditFieldLabel = uilabel(app.ProjectPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.ProjectPanel, 'text');
            % Show the figure after all components are created
            app.SpectralMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralMeasurements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralMeasurement)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralMeasurement)
        end
    end
end