classdef SpectralMeasurements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralMeasurement            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ProjectMenu                    matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ProjectSave                    matlab.ui.container.Menu
        ProjectNew                     matlab.ui.container.Menu
        ProjectOpen                    matlab.ui.container.Menu
        SweepMenu                      matlab.ui.container.Menu
        SweepSave                      matlab.ui.container.Menu
        SweepOpen                      matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        EffectiveIndexCalculationMenu  matlab.ui.container.Menu
        ConnectionSetupMenu            matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        TabGroup2                      matlab.ui.container.TabGroup
        ProjectTab                     matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        MeasurementsPanel              matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        tblMeasurements                matlab.ui.control.Table
        btnDeleteMeasurement           matlab.ui.control.Button
        ProjectManagementLabel         matlab.ui.control.Label
        PlottingPanel                  matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        btnPlot                        matlab.ui.control.Button
        xaxisDropDownLabel             matlab.ui.control.Label
        optXaxis                       matlab.ui.control.DropDown
        yaxisDropDownLabel             matlab.ui.control.Label
        optYaxis                       matlab.ui.control.DropDown
        zcoloraxisDropDownLabel        matlab.ui.control.Label
        optZaxis                       matlab.ui.control.DropDown
        LMS_1Tab                       matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        LightwaveMeasurementSystem01Label  matlab.ui.control.Label
        ConnectionPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        InstrumentDropDownLabel        matlab.ui.control.Label
        panel01_InstrSelect1           matlab.ui.control.DropDown
        panel01_ConnectButton          matlab.ui.control.Button
        panel01_instrConnStat          matlab.ui.control.Lamp
        LaserOutputLampLabel           matlab.ui.control.Label
        panel01_LaserOutputLamp        matlab.ui.control.Lamp
        LaserControlPanel_2            matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        WavelengthSpinnerLabel         matlab.ui.control.Label
        panel01_txtWavelength          matlab.ui.control.Spinner
        OutputPowerSpinnerLabel        matlab.ui.control.Label
        panel01_txtPower               matlab.ui.control.Spinner
        nmLabel                        matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        panel01_btnLaserOn             matlab.ui.control.StateButton
        PowerSensorPanel               matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        AveragingTSpinnerLabel         matlab.ui.control.Label
        panel01_AveragingTSpinner      matlab.ui.control.Spinner
        RangeDropDownLabel             matlab.ui.control.Label
        panel01_RangeDropDown          matlab.ui.control.DropDown
        pwrMeas1                       matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        panel01_btnPowerScan           matlab.ui.control.StateButton
        panel01_cbAutoRange            matlab.ui.control.CheckBox
        dBmLabel_3                     matlab.ui.control.Label
        OpticalSwitchPanel_2           matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        panel01_swAKnob                matlab.ui.control.DiscreteKnob
        LMS_2Tab                       matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        ConnectionPanel_2              matlab.ui.container.Panel
        GridLayout8_2                  matlab.ui.container.GridLayout
        InstrumentDropDownLabel_2      matlab.ui.control.Label
        panel01_InstrSelect1_2         matlab.ui.control.DropDown
        panel01_ConnectButton_2        matlab.ui.control.Button
        panel01_instrConnStat_2        matlab.ui.control.Lamp
        LaserOutputLampLabel_2         matlab.ui.control.Label
        panel01_LaserOutputLamp_2      matlab.ui.control.Lamp
        LaserControlPanel_3            matlab.ui.container.Panel
        GridLayout9_2                  matlab.ui.container.GridLayout
        WavelengthSpinnerLabel_2       matlab.ui.control.Label
        panel01_txtWavelength_2        matlab.ui.control.Spinner
        OutputPowerSpinnerLabel_2      matlab.ui.control.Label
        panel01_txtPower_2             matlab.ui.control.Spinner
        nmLabel_11                     matlab.ui.control.Label
        dBmLabel_6                     matlab.ui.control.Label
        panel01_btnLaserOn_2           matlab.ui.control.StateButton
        PowerSensorPanel_2             matlab.ui.container.Panel
        GridLayout10_2                 matlab.ui.container.GridLayout
        AveragingTSpinnerLabel_2       matlab.ui.control.Label
        panel01_AveragingTSpinner_2    matlab.ui.control.Spinner
        RangeDropDownLabel_2           matlab.ui.control.Label
        panel01_RangeDropDown_2        matlab.ui.control.DropDown
        pwrMeas1_2                     matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        panel01_btnPowerScan_2         matlab.ui.control.StateButton
        panel01_cbAutoRange_2          matlab.ui.control.CheckBox
        dBmLabel_7                     matlab.ui.control.Label
        OpticalSwitchPanel_3           matlab.ui.container.Panel
        GridLayout11_2                 matlab.ui.container.GridLayout
        panel01_swAKnob_2              matlab.ui.control.DiscreteKnob
        LightwaveMeasurementSystem02Label  matlab.ui.control.Label
        SweepTab                       matlab.ui.container.Tab
        GridLayout17                   matlab.ui.container.GridLayout
        SweepParametersLabel           matlab.ui.control.Label
        ScanParametersPanel            matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        ScanStartSpinnerLabel          matlab.ui.control.Label
        spinStart                      matlab.ui.control.Spinner
        ScanStopSpinnerLabel           matlab.ui.control.Label
        spinStop                       matlab.ui.control.Spinner
        StepSizeSpinnerLabel           matlab.ui.control.Label
        spinStep                       matlab.ui.control.Spinner
        ScanSpeedLabel                 matlab.ui.control.Label
        spinSpeed                      matlab.ui.control.Spinner
        RangeLabel                     matlab.ui.control.Label
        RangeDropDown_Sweep            matlab.ui.control.DropDown
        nmLabel_2                      matlab.ui.control.Label
        nmLabel_3                      matlab.ui.control.Label
        nmLabel_4                      matlab.ui.control.Label
        nmsLabel                       matlab.ui.control.Label
        DataAnalysisTab_2              matlab.ui.container.Tab
        GridLayout16                   matlab.ui.container.GridLayout
        Peakfinder1RedPanel            matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        HeightLabel                    matlab.ui.control.Label
        HeightSpinner                  matlab.ui.control.Spinner
        ProminenceLabel                matlab.ui.control.Label
        ProminenceSpinner              matlab.ui.control.Spinner
        MaxWidthLabel                  matlab.ui.control.Label
        MaxWidthSpinner                matlab.ui.control.Spinner
        MinWidthLabel                  matlab.ui.control.Label
        MinWidthSpinner                matlab.ui.control.Spinner
        PeakSpacingLabel               matlab.ui.control.Label
        DistanceSpinner                matlab.ui.control.Spinner
        dBmLabel_4                     matlab.ui.control.Label
        nmLabel_5                      matlab.ui.control.Label
        nmLabel_6                      matlab.ui.control.Label
        nmLabel_7                      matlab.ui.control.Label
        MinPeakHeightCheckBox          matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox      matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox        matlab.ui.control.CheckBox
        InterferenceAnalysisPanel      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        LnmLabel                       matlab.ui.control.Label
        txtResL                        matlab.ui.control.NumericEditField
        TypeofdeviceDropDownLabel      matlab.ui.control.Label
        optTOD                         matlab.ui.control.DropDown
        neffectiveEditFieldLabel       matlab.ui.control.Label
        txtneff                        matlab.ui.control.NumericEditField
        DataAnalysisParametersLabel    matlab.ui.control.Label
        ConfigTab                      matlab.ui.container.Tab
        tblConfig                      matlab.ui.control.Table
        SaveButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        UIAxes01                       matlab.ui.control.UIAxes
        logger                         matlab.ui.control.TextArea
        btnRunSweep                    matlab.ui.control.StateButton
        tblResonanceAnalysis           matlab.ui.control.Table
        FindPeaksButton                matlab.ui.control.Button
        DisplayPeaksCheckBox           matlab.ui.control.CheckBox
        Peakfinder2GreenPanel          matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        HeightSpinner_2Label           matlab.ui.control.Label
        HeightSpinner_2                matlab.ui.control.Spinner
        ProminenceSpinner_2Label       matlab.ui.control.Label
        ProminenceSpinner_2            matlab.ui.control.Spinner
        MaxWidthSpinner_2Label         matlab.ui.control.Label
        MaxWidthSpinner_2              matlab.ui.control.Spinner
        MinWidthSpinner_2Label         matlab.ui.control.Label
        MinWidthSpinner_2              matlab.ui.control.Spinner
        PeakSpacingLabel_2             matlab.ui.control.Label
        DistanceSpinner_2              matlab.ui.control.Spinner
        dBmLabel_5                     matlab.ui.control.Label
        nmLabel_8                      matlab.ui.control.Label
        nmLabel_9                      matlab.ui.control.Label
        nmLabel_10                     matlab.ui.control.Label
        MinPeakHeightCheckBox_2        matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox_2    matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox_2      matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        connection                 
    end
    
    properties (Access = private)
        Timer % Description
        connPanel01 % Description
        LockLaser % Description
        saveFolder % Description
        mData % Description
        
        measurement % Structure with members {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        project % Structure with measurements of type sweep {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        selectedRow % Row selected on the measurements tableUI
    end
    
    methods (Access = private)
        
        function [status, idn] = sm_connect(app,g)
            status = 0; idn = 0;
            try
                if strcmp(g.status,'open'), fclose(g); end %Force to reset the connection.
                fopen(g); 
                idn = send(g,'*IDN?');
                status = 1;
                fclose(g); 
            catch EM
                log(app,EM.message)
                
            end
        end
        
        function TimerFcn(app, src, event)
        %This function reads the power from the sensor 1
            if strcmp(app.panel01_instrConnStat.Enable,'on')
                g = app.connPanel01.gpib;
                if strcmp(g.status,'open') 
                    val = str2num(send(g,'read1:pow?'))
                    app.pwrMeas1.Text = num2str(val,'%.4f');
                end
            end
        end
        
        function laserStat = switchLaser(app,g, laserStat)
            %Check if the laser is locked and unlock in necessary
            app.LockLaser = false;
            fprintf(g,'lock?');lockStat = str2double(fscanf(g));
            if lockStat
                fprintf(g,'lock 0,1234');
                app.LockLaser = true;
                log(app,'Unlocking the laser using the default password.')
                lockStat = send(g,'lock?');
                if ~lockStat, log(app,'Laser unlocked.');
                else, log(app,'Unabled to unlock the laser.');
                end
            end
                
            %Switch the laser
            send(g,"outp "+laserStat);
            laserStat = str2num(send(g,"outp?"));
            app.panel01_LaserOutputLamp.Enable = laserStat;
            
            %Lock the laser if the initial state was locked
            if ~laserStat && app.LockLaser, fprintf(g,'lock 1,1234');end
        end
        
        function wav = setLaserWav(app,g, wav)
            %Sets the laser01 central wavelenght and returns the
            %configured value in nm

            send(g,"sour0:chan1:wav " + num2str(wav,'%5.3f')+'nm');
            wav = str2num(send(g,'sour0:chan1:wav?'))*1e9;
        end
        
        function pow = setLaserPower(app,g,pow)
        
            %Set the power unit as dBm
            send(g,'sour0:pow:unit dbm');
            send(g,"sour0:chan1:pow " + num2str(pow,'%5.0f'));
            str2num(send(g,'sour0:chan1:pow?'));
        end
        
        function enableControls(app, value)
            app.panel01_instrConnStat.Enable = value;                      
            app.panel01_swAKnob.Enable = value;
            app.btnRunSweep.Enable = value;
            app.panel01_btnPowerScan.Enable = value;
            app.panel01_btnLaserOn.Enable = value;
        end
        
        function setPowerScan(app,value)
            g = app.connPanel01.gpib;
            if value
                try  
                    fopen(g);
                    send(g,'*CLS');
                    opcStat = send(g,'*OPC?'); 
                    if opcStat
                        range = app.panel01_RangeDropDown.Value;    %Range in dBm
                        avgT = app.panel01_AveragingTSpinner.Value*1e-3; %Averaging tim ein seconds
                        
                        send(g,"sens1:pow:atim "+ num2str(avgT,'%5.2f') + 's');
                        avgT = str2num(send(g,"sens1:pow:atim?"));
                        if ~app.panel01_cbAutoRange.Value
                            send(g,"sens1:pow:rang:auto 0");
                            send(g,"sens1:pow:rang "+ range);
                            range =  str2num(send(g,"sens1:pow:rang?"));
                            log(app,"Configured sensor with averaging time: " + avgT + " and range " + num2str(range,'%5.2f') +' dBm.');
                        else
                            send(g,"sens1:pow:rang:auto 1");
                            log(app,"Configured sensor with averaging time: "+avgT+" and automatic range.")
                        end
                        
                        start(app.Timer);
                    else
                        app.Switch.Value = false;
                        fclose(g);
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    stop(app.Timer);
                    app.panel01_btnPowerScan.Value = false;
                    if ~isempty(app.connPanel01.gpib), fclose(app.connPanel01.gpib);end
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            else
                stop(app.Timer);
                fclose(g);
            end
        end
        
        function [pks,wav,filter] = SM_FindPeaks(app,spectra)
            
            filterStat{1} = [ app.MinPeakHeightCheckBox.Value,...
                        app.MinPeakProminenceCheckBox.Value,...
                        app.MaxPeakWidthCheckBox.Value,...
                        app.MinPeakWidthCheckBox.Value,...
                        app.MinPeakDistanceCheckBox.Value];
            
            filterParam{1} = [app.HeightSpinner.Value,...
                        app.ProminenceSpinner.Value,...
                        app.MaxWidthSpinner.Value,...
                        app.MinWidthSpinner.Value,...
                        app.DistanceSpinner.Value
                        ];
            
           filterStat{2} = [ app.MinPeakHeightCheckBox_2.Value,...
                        app.MinPeakProminenceCheckBox_2.Value,...
                        app.MaxPeakWidthCheckBox_2.Value,...
                        app.MinPeakWidthCheckBox_2.Value,...
                        app.MinPeakDistanceCheckBox_2.Value];
                    
            filterParam{2} = [app.HeightSpinner_2.Value,...
                        app.ProminenceSpinner_2.Value,...
                        app.MaxWidthSpinner_2.Value,...
                        app.MinWidthSpinner_2.Value,...
                        app.DistanceSpinner_2.Value
                        ];   
            filter.stat=filterStat{1};
            filter.param = filterParam{1};
            
            [pks,wav]=getPeaks(spectra,filterStat{1},filterParam{1});
        end
        
        function project2h5(app,file)
            app.project.type = 'Spectral Measurements Project';
            app.project.datasize = length(app.project.measurements);
            struct2h5(file, '/project', app.project)
        end
        
        function updateUIData(app)
            cla(app.UIAxes01);
            for i = 1:length(app.selectedRow)
                ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                index = getIndex(app.project.measurements,ID);
                app.measurement = app.project.measurements{index};
                
                %Plot and display results of the active dataset and analytsis 
                pks = {}; try pks = app.measurement.peakAnalysis.pks; catch, end
                wav = {}; try wav = app.measurement.peakAnalysis.wav; catch, end
                
                if  ~isempty(pks), if pks == 0, pks = {};end,end
                
                spectra = app.measurement.spectra;
                
                if ~isempty(pks)
                    ran = max(spectra(:,1))-min(spectra(:,1));
                    if app.DisplayPeaksCheckBox.Value
                        plot (app.UIAxes01,spectra(:,1),spectra(:,2), wav, pks,'V','MarkerFaceColor','red'); 
                        xlabels = cellstr(num2str(wav*1e9));
                        lbls=cell(length(pks),1);
                        for j =1:length(pks)
                            lbls{j}=xlabels{j};%Label for the plot peak 
                        end
                        text(app.UIAxes01,wav+ran/40, pks,lbls);
                    else
                         plot (app.UIAxes01,spectra(:,1),spectra(:,2));
                    end
                    
                    if i == length(app.selectedRow)
                        %Update the table only in the last iteration
                        t = getTableAnalysis(app.measurement.peakAnalysis);
                        app.tblResonanceAnalysis.Data = t;
                    end
                else
                    plot (app.UIAxes01,spectra(:,1),spectra(:,2));
                end
                hold(app.UIAxes01,'on');
            end
            hold(app.UIAxes01,'off');  
        end
        
    end
    
    methods (Access = public)
        
        function log(app,logText)
            oldlogText = app.logger.Value;
            maxLines = 100;
            if length(oldlogText)<maxLines
                logText = [logText; oldlogText];
            else
                logText = [logText;oldlogText(1:maxLines-1)];
            end
            app.logger.Value = logText;
        end
        
        function setWorkspace(app, configData)
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.tblConfig.Data = configData;
            
            %Fill the combo boxes with the instruments available in the
            %configuration table
            comboStrings = strcat(string(configData.ID),'-',configData.Name);
            comboValues = configData.ID;
            app.panel01_InstrSelect1.Items = comboStrings;
            app.panel01_InstrSelect1.ItemsData = comboValues;
            
            
            %Define the GPIB connections
            app.connection = [];
            for i =1:size(configData,1)
                c = configData(i,:);
                app.connection(i).config = c;
                app.connection(i).ID = string(c.ID);
                app.connection(i).gpib = gpib(string(c.vendor), c.Board, c.GPIB);
                app.connection(i).status = false;
            end
            
            %Initilize an empty project variable
            app.project.measurements = {};
            app.project.filename = '';
            ID = zeros(0,1); Var1 = ID; Var2 = ID; Var3 = ID; Date = strings(0,1); %Initialize data for the table
            t = table(ID,Date,Var1,Var2,Var3);
            app.tblMeasurements.Data = t;
            
            %Set the default save directory
            app.saveFolder = "C:\Users\Public\Documents\Shared Data\Users\";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath('dependencies\')
            app.Image.ImageSource = 'logo.png';
            %Clear any existing instruments
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end

            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
            setWorkspace(app, configData)
            
            app.Timer = timer('Period',0.3,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;


        end

        % Close request function: SpectralMeasurement
        function CloseRequest(app, event)
            %Make sure to close all connections and delete the objects
            
            for i = 1:length(app.connection)
                try 
                    fclose(app.connection(i).gpib);
                catch EM
                end
                delete(app.connection(i).gpib);
                
            end
            delete(app)
            clear app
        end

        % Menu selected function: SweepSave
        function SweepSaveSelected(app, event)
        
            
            file = datestr(now,'yyyymmdd')+"_Spectra";
            path = app.saveFolder;
            
            [file,path] = uiputfile('*.mat','Export sweep data as',strcat(path,file));
            figure(app.SpectralMeasurement);
            if path(1)~=0 && ~isempty(app.selectedRow)  
                if length(app.selectedRow)>1
                    for i = 1:length(app.selectedRow)
                        ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                        index = getIndex(app.project.measurements,ID);
                        data = app.project.measurements{index}.spectra;
                        
                        file2 = [file(1:end-4),'_',num2str(i),'.mat'];
                        save([path,file2],'data');
                    end
                else
                    data = app.measurement.spectra;
                    save([path,file],'data');
                end
                app.saveFolder = path;
            end
           
        end

        % Menu selected function: SweepOpen
        function SweepImport(app, event)
            %This functions opens raw data of a spectral measurement and
            %loads it to the project, initializing the diifrenet measurement parameters. 
                path = app.saveFolder;
                [file,path] = uigetfile(strcat(path,"*.mat"),'MultiSelect', 'on');
                
                if ~isempty(path)
                    if path ~= 0
                        if ischar(file), files{1} = file;
                        else, files = file; end
                        app.saveFolder = path;
                        sn = length(app.project.measurements);
                        log(app,'A random ID is assigned to the loaded data.');
                        for i = 1:length(files)
                            data = load(strcat(path,files{i}));
                            spectra = data.data;
                            
                            new_measurement = newMeasurement(spectra);
                            %DEBUG: Add a routine to check that an ID has no replicas.
                            app.project.measurements{sn+i} = new_measurement;
                        end
                        
                        %Populate the data for the project table and save
                        %the active work datasets
                        t = getTableData(app.project.measurements);
                        app.tblMeasurements.Data = t;
                        app.measurement = new_measurement;
                        plot(app.UIAxes01,app.measurement.spectra(:,1),app.measurement.spectra(:,2));
                    end  
                end
                figure(app.SpectralMeasurement);
%             catch EM
%                 log(app,EM.message)
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectralMeasurement.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {640, 640};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {425, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: btnRunSweep
        function btnRunSweepPushed(app, event)
            value = app.btnRunSweep.Value;
            if value
                stop(app.Timer);
                app.panel01_btnPowerScan.Value = 0;
                app.panel01_btnLaserOn.Value = 0;

                g = app.connPanel01.gpib;
                if app.connPanel01.status
                    openGPIB(g);
                    send(g,'*CLS');
                    opcStat = str2double(send(g,'*OPC?'));
                    
                    if opcStat
                        enableControls(app, 0);

                        new_measurement = newMeasurement();
                        
                        %Read the user parameters from the UI
                            sweepParam = {};
                            sweepParam.channel = 1;
                            sweepParam.power = 3;             % Power in dBm (3dBm = 2mW)  -- DEBUG: Change to user input
                            sweepParam.starWav =  app.spinStart.Value*1e-9;  % start wavelength in m
                            sweepParam.stopWav =  app.spinStop.Value*1e-9;   % stop wavelength in m
                            sweepParam.step =     app.spinStep.Value*1e-9;   % stepsize in meters
                            sweepParam.sweepSpeed = app.spinSpeed.Value*1e-9;  % sweep speed in m/s
                            sweepParam.range = app.RangeDropDown_Sweep.Value;  % range in dBm in steps of 10, between [-70 and 40];
                        new_measurement.sweepParam = sweepParam;
                        
                        %Turn off laser  and park at the start wavelength
                        switchLaser(app,g, 0);
                        setLaserWav(app,g, sweepParam.starWav*1e9);
                        pause(1); 
                        
                        % Prepare scan
                        [new_measurement.sweepParam,sweepP] = prepareScan(g,sweepParam);
                        if sweepP.stat
                            try 
                                new_measurement.spectra = runSweep(g,new_measurement.sweepParam);
                            catch ME
                                log(app,"Error: "+ME.message);
                                log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                            end
                        end
                        
                        %Update the global variables
                        app.measurement = new_measurement;
                        dataSize = length(app.project.measurements);
                        app.project.measurements{dataSize+1}=new_measurement;
                        
                       
                        %Populate the data for the project table and save
                        %the active work datasets
                        t = getTableData(app.project.measurements);
                        app.tblMeasurements.Data = t;
                        app.measurement = new_measurement;
                        plot(app.UIAxes01,app.measurement.spectra(:,1),app.measurement.spectra(:,2));
                        app.selectedRow = dataSize+1;
                        
                        
                        %updateUIData(app);
                        enableControls(app, 1);
                    end
                    fclose(g);
                end
            end
            app.btnRunSweep.Value = 0;
            enableControls(app, 1);
        end

        % Button pushed function: SaveButton
        function tools_btnSaveConfigPushed(app, event)
            configData = app.tblConfig.Data;
            save('config.mat', 'configData','-mat');
            
            %Updates workspace varibales (this will delete any existing
            %connections)
            setWorkspace(app, configData);
        end

        % Value changed function: panel01_btnPowerScan
        function panel01_btnPowerScanPushed(app, event)
            %This function will open the COM with the instrument and leave
            %it open for increase performance, and start the timed function
            %to query for the measurements in the Power Sensor.
            value = app.panel01_btnPowerScan.Value;
            setPowerScan(app, value);  
        end

        % Button pushed function: panel01_ConnectButton
        function panel01_btnConnectPushed(app, event)
            t = app.tblConfig.Data;
            instrID = string(app.panel01_InstrSelect1.Value);
            status = false;
            
            clear c
            for connIndex = 1:length(app.connection)
                if strcmp(app.connection(connIndex).ID,instrID)
                    c = app.connection(connIndex);
                    g = c.gpib;
                    break
                end
            end
            
            enableControls(app,0);    
            if t(instrID,:).Enabled
                [status, idn] = sm_connect(app,g);
                if ~status
                    %try to restart the connection
                    
                    config = c.config;
                    c.gpib = gpib(string(config.vendor), config.Board, config.GPIB);
                    [status, idn] = sm_connect(app,g);
                end
                
                if status
                    log(app,"Connected to "+idn);
                    app.panel01_instrConnStat.Enable='on';
                    
                    switch_slot = 4;
                    fopen(c.gpib);
                    swStat = send(c.gpib,"rout"+switch_slot+":chan1?"); %%CHECK THIS
                    
                    enableControls(app,1);
                    c.status = true;
                    app.connPanel01 = c;    %set the connection for the 1st panel as the one found for the selected ID.
                    
                    fclose(c.gpib);
                else
                    log(app,"The selected instrument does not respond.")
                    c.status = false;
                    app.connPanel01 = c;
                end
            else
                log(app,"The selected instrument is not enabled in the configuration.")
                
            end
                    

            if (~status)
                log(app,"Failed to connect to the measurement system.")
            end
        end

        % Value changed function: panel01_btnLaserOn
        function panel01_btnLaserOnPushed(app, event)
        %Sends a command to turn the laser on and updates the laser
        %parameters according to the opnes set.
        
            value = double(app.panel01_btnLaserOn.Value);
            closeConn = false;
            g = app.connPanel01.gpib;
            try  
                if strcmp(g.status,'closed')
                    closeConn = true; %Id the connection is closed, open it and use this flag to close it at the end
                    fopen(g);
                end
                
                %Check if the intsrument can receive commands (this could
                %be on a while to wait until the instrument is ready).
                
                opcStat = str2double(send(g,'*OPC?'));
                if opcStat
                    laserStat = switchLaser(app,app.connPanel01.gpib,value);
                    if laserStat
                        dblWav = setLaserWav(app,app.connPanel01.gpib, app.panel01_txtWavelength.Value);
                        dblPow = setLaserPower(app,app.connPanel01.gpib, app.panel01_txtPower.Value);
                        
                        app.panel01_txtWavelength.Value = dblWav;
                        app.panel01_txtPower.Value= dblPow;
                    end
                else
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"The instrument is not ready.")
                end
            catch ME
                app.panel01_btnLaserOn.Value = false;
                log(app,"Error: "+ME.message);
                log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            
            if closeConn, fclose(app.connPanel01.gpib);end %Close the connection if the flag is raised


        end

        % Value changed function: panel01_cbAutoRange
        function panel01_cbAutoRangeValueChanged(app, event)
            value = app.panel01_cbAutoRange.Value;
            if value
                app.panel01_RangeDropDown.Enable = false;
            else
                app.panel01_RangeDropDown.Enable = true;
            end
        end

        % Value changed function: panel01_txtWavelength
        function panel01_txtWavelengthValueChanged(app, event)
            laserStat = double(app.panel01_btnLaserOn.Value);
            if laserStat
                closeConn = false;
                try  
                    if strcmp(app.connPanel01.gpib.status,'closed') %Use this in other parts of the code as well to check for the connection status
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the instrument can receive commands
                    
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblWav = app.panel01_txtWavelength.Value;
                        if laserStat, dblWav = setLaserWav(app,app.connPanel01.gpib, dblWav); end
                        app.panel01_txtWavelength.Value = dblWav;
                    end
                catch ME
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end
        end

        % Value changed function: panel01_txtPower
        function panel01_txtPowerValueChanged(app, event)

            if app.connPanel01.status
                
                try  
                    closeConn = false;
                    if strcmp(app.connPanel01.gpib.status,'closed')
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the ind=strument can receive commands
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblPow = app.panel01_txtPower.Value;
                        app.panel01_txtPower.Value = setLaserPower(app,app.connPanel01.gpib, dblPow);
                    end
                catch ME
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end      
        end

        % Value changed function: panel01_swAKnob
        function panel01_swValueChanged(app, event)
            if app.connPanel01.status   
                value = app.panel01_swAKnob.Value;
                g = app.connPanel01.gpib;
                try 
                    closeConn = openGPIB(g);
                    stat = str2double(send(g,'*OPC?')); %Check if the intsrument can receive commands
                    if str2double(stat)
                        send(g,"rout4:chan1 A,"+value);
                        swStat = send(g,"rout4:chan1?");
                        app.panel01_swAKnob.Value = swStat(3); 
                        
                    else
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(g);end
            end
        end

        % Value changed function: panel01_RangeDropDown
        function panel01_RangeDropDownValueChanged(app, event)
            value = app.panel01_RangeDropDown.Value;
            
        end

        % Button pushed function: FindPeaksButton
        function btnFindPeakPushed(app, event)
            for i = 1:length(app.selectedRow)
                ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                index = getIndex(app.project.measurements,ID);
                app.measurement = app.project.measurements{index};
                
                spectra = app.measurement.spectra;
                [pks,wav,filter] = SM_FindPeaks(app,spectra);
                if ~isempty(pks) 
                    %%%%%%% DEBUG: READ PARAMETERS FROM USER
                    L = app.txtResL.Value;
                    ne = app.txtneff.Value;
                    type = app.optTOD.Value;
                    windowSc = 0.20;
                    
                    analysisParam = {};
                        analysisParam.filter = filter;
                        analysisParam.type = type;
                        analysisParam.L = L;
                        analysisParam.ne = ne;
                        analysisParam.windowSc = windowSc;
                    app.measurement.analysisParam = analysisParam;
                    
                    analysis = resonanceAnalysis(spectra,pks, wav,[L, ne, windowSc]);
                    app.measurement.peakAnalysis = analysis;          
                    
                    %Update project info
                    app.project.measurements{index} = app.measurement;
                end
                
            end
            
            updateUIData(app);
        end

        % Menu selected function: EffectiveIndexCalculationMenu
        function tools_modeCalculatorSelected(app, event)
            ModeCalculator;
        end

        % Menu selected function: ProjectSave
        function ProjectSaveAsSelected(app, event)
            %Save Project As
            [file,path] = uiputfile('*.smp','Save project as',strcat(app.saveFolder,'project'));
            filename =[path,file];
            if path~=0 
                try delete(filename), catch, end 
                app.project.filename = filename;
                project2h5(app,filename)
                app.saveFolder = path;
            end
        end

        % Menu selected function: ProjectOpen
        function ProjectOpenSelected(app, event)
            %This functions opens a  spectral measurements project and
            %saves it to the project data structure 
                path = app.saveFolder;
                [file,path] = uigetfile(strcat(path,"*.smp"),'MultiSelect', 'off');
                figure(app.SpectralMeasurement);
                if ~isempty(path)
                    if path ~= 0
                        ProjectNewSelected(app, event);
                        app.project.filename = [path, file];
                        app.saveFolder = path;
                        
                        loc = '/project';
                        struct = h52struct(app.project.filename, loc);
                       
                        app.project.measurements = struct.measurements;
                        
                        
                        %Populate the data for the project table
                        t = getTableData(app.project.measurements);
                        app.tblMeasurements.Data = t; 
                        
                        log(app,['Project opened from: ', file]);
                    end  
                end


        end

        % Menu selected function: ProjectNew
        function ProjectNewSelected(app, event)
            app.project.measurements = {};
            app.project.filename = '';
            ID = zeros(0,1); Var1 = ID; Var2 = ID; Var3 = ID; Date = strings(0,1); %Initialize data for the table
            t = table(ID,Date,Var1,Var2,Var3);
            app.tblMeasurements.Data = t;
            cla(app.UIAxes01)
        end

        % Cell edit callback: tblMeasurements
        function ProjectTblMeasurementsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            ID = app.tblMeasurements.Data(indices(1),:).ID;
            i = getIndex(app.project.measurements,ID);
            app.project.measurements{i}.var(indices(2)-2)=newData;
            
        end

        % Cell selection callback: tblMeasurements
        function ProjectTblMeasurementsCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow = indices(:,1);
                        
            updateUIData(app);
        end

        % Button pushed function: btnDeleteMeasurement
        function ProjectDeleteMeasurement(app, event)
            if app.selectedRow < length(app.project.measurements) && app.selectedRow >0
                ID = app.tblMeasurements.Data(app.selectedRow,:).ID;
                index = getIndex(app.project.measurements,ID);                
                app.project.measurements{index} = []; %Delete the selected row 
               
                %Update the data for the project table
                t = getTableData(app.project.measurements);
                app.tblMeasurements.Data = t;
            end
        end

        % Menu selected function: SaveMenu
        function ProjectSaveSelected(app, event)
            file = app.project.filename;    
            if isempty(file)
                ProjectSaveAsSelected(app, event);
            else
                try delete(file), catch, end 
                project2h5(app,file)
            end
        end

        % Value changed function: DisplayPeaksCheckBox
        function DisplayPeaksCheckBoxValueChanged(app, event)
            value = app.DisplayPeaksCheckBox.Value;
            updateUIData(app);
        end

        % Button pushed function: btnPlot
        function btnPlotPushed(app, event)
            zopt = app.optZaxis.Value;
            xopt = app.optXaxis.Value;
            yopt = app.optYaxis.Value;
            
            
            x = get1DData(app.project.measurements,xopt);
            y = get1DData(app.project.measurements,yopt);
            
            figure(1); ax = gca; cla(ax);
            if strcmp(zopt, "Not used")
                hold (ax,'on');
                for i = 1:size(y,2)
                    try 
                        plot(ax,x(:,i),y(:,i));
                    catch ME
                        log(app,ME.message);
                    end
                    set(ax,'xlimMode', 'auto');
                    grid on;
                end
                hold (ax,'off');
            else
                %Color Plot 
                if strcmp(xopt,"wavelength")||strcmp(yopt,"wavelength")
                    z = get1DData(app.project.measurements,zopt);
                    
                    
                    try 
                        if length(x) == size(z,1)
                            x = x(:,1);
                            pcolor(ax,x,y,z');
                        else
                            pcolor(ax,x,y,z);
                        end
                    catch EM
                        log(app,EM.message);
                    end
                    shading('interp');
                    colormap('hot');
                    
                    
                    h = colorbar(ax);
                    set(get(h,'title'),'string',zopt);
                else
                    log(app,"Either x or y need to be the wavelength to generate colorplots.");
                end
            end
            
            xlabel(ax,xopt);
            ylabel(ax,yopt);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralMeasurement = uifigure('Visible', 'off');
            app.SpectralMeasurement.Name = 'Spectral Measurements';
            app.SpectralMeasurement.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.SpectralMeasurement.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.SpectralMeasurement);
            app.FileMenu.Text = 'File';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSaveSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ProjectSave = uimenu(app.ProjectMenu);
            app.ProjectSave.MenuSelectedFcn = createCallbackFcn(app, @ProjectSaveAsSelected, true);
            app.ProjectSave.Text = 'Save As';
            app.ProjectNew = uimenu(app.ProjectMenu);
            app.ProjectNew.MenuSelectedFcn = createCallbackFcn(app, @ProjectNewSelected, true);
            app.ProjectNew.Text = 'New';
            app.ProjectOpen = uimenu(app.ProjectMenu);
            app.ProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @ProjectOpenSelected, true);
            app.ProjectOpen.Text = 'Open';
            app.SweepMenu = uimenu(app.FileMenu);
            app.SweepMenu.Text = 'Sweep';
            app.SweepSave = uimenu(app.SweepMenu);
            app.SweepSave.MenuSelectedFcn = createCallbackFcn(app, @SweepSaveSelected, true);
            app.SweepSave.Text = 'Export';
            app.SweepOpen = uimenu(app.SweepMenu);
            app.SweepOpen.MenuSelectedFcn = createCallbackFcn(app, @SweepImport, true);
            app.SweepOpen.Text = 'Import';
            app.ToolsMenu = uimenu(app.SpectralMeasurement);
            app.ToolsMenu.Text = 'Tools';
            app.EffectiveIndexCalculationMenu = uimenu(app.ToolsMenu);
            app.EffectiveIndexCalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @tools_modeCalculatorSelected, true);
            app.EffectiveIndexCalculationMenu.Text = 'Effective Index Calculation';
            app.ConnectionSetupMenu = uimenu(app.ToolsMenu);
            app.ConnectionSetupMenu.Visible = 'off';
            app.ConnectionSetupMenu.Text = 'Connection Setup';
            app.GridLayout = uigridlayout(app.SpectralMeasurement);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ProjectTab = uitab(app.TabGroup2);
            app.ProjectTab.Title = 'Project';
            app.GridLayout15 = uigridlayout(app.ProjectTab);
            app.MeasurementsPanel = uipanel(app.GridLayout15);
            app.MeasurementsPanel.Title = 'Measurements';
            app.GridLayout19 = uigridlayout(app.MeasurementsPanel);
            app.tblMeasurements = uitable(app.GridLayout19);
            app.tblMeasurements.ColumnName = {'ID'; 'Time'; 'Var 1'; 'Var 2'; 'Var 3'};
            app.tblMeasurements.RowName = {};
            app.tblMeasurements.ColumnSortable = [true true false false false];
            app.tblMeasurements.ColumnEditable = [false false true true true];
            app.tblMeasurements.CellEditCallback = createCallbackFcn(app, @ProjectTblMeasurementsCellEdit, true);
            app.tblMeasurements.CellSelectionCallback = createCallbackFcn(app, @ProjectTblMeasurementsCellSelection, true);
            app.btnDeleteMeasurement = uibutton(app.GridLayout19, 'push');
            app.btnDeleteMeasurement.ButtonPushedFcn = createCallbackFcn(app, @ProjectDeleteMeasurement, true);
            app.btnDeleteMeasurement.Text = '-';
            app.ProjectManagementLabel = uilabel(app.GridLayout15);
            app.ProjectManagementLabel.Text = 'Project Management';
            app.PlottingPanel = uipanel(app.GridLayout15);
            app.PlottingPanel.Title = 'Plotting';
            app.GridLayout20 = uigridlayout(app.PlottingPanel);
            app.btnPlot = uibutton(app.GridLayout20, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotPushed, true);
            app.btnPlot.Text = 'Plot';
            app.xaxisDropDownLabel = uilabel(app.GridLayout20);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.optXaxis = uidropdown(app.GridLayout20);
            app.optXaxis.Items = {'wavelength', 'time', 'var 1', 'var 2', 'var 3'};
            app.optXaxis.Value = 'wavelength';
            app.yaxisDropDownLabel = uilabel(app.GridLayout20);
            app.yaxisDropDownLabel.Text = 'y axis';
            app.optYaxis = uidropdown(app.GridLayout20);
            app.optYaxis.Items = {'wavelength', 'intensity', 'Resonance Wav.', 'FSR', 'FWHM', 'Q. Factor', 'Group Index'};
            app.optYaxis.Value = 'intensity';
            app.zcoloraxisDropDownLabel = uilabel(app.GridLayout20);
            app.zcoloraxisDropDownLabel.Text = 'z (color) axis';
            app.optZaxis = uidropdown(app.GridLayout20);
            app.optZaxis.Items = {'Not used', 'intensity'};
            app.optZaxis.Value = 'Not used';
            app.LMS_1Tab = uitab(app.TabGroup2);
            app.LMS_1Tab.Title = 'LMS_1';
            app.GridLayout13 = uigridlayout(app.LMS_1Tab);
            app.LightwaveMeasurementSystem01Label = uilabel(app.GridLayout13);
            app.LightwaveMeasurementSystem01Label.Text = 'Lightwave Measurement System 01';
            app.ConnectionPanel = uipanel(app.GridLayout13);
            app.ConnectionPanel.Title = 'Connection';
            app.GridLayout8 = uigridlayout(app.ConnectionPanel);
            app.InstrumentDropDownLabel = uilabel(app.GridLayout8);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.panel01_InstrSelect1 = uidropdown(app.GridLayout8);
            app.panel01_InstrSelect1.ItemsData = {'1001'};
            app.panel01_InstrSelect1.Value = '1001';
            app.panel01_ConnectButton = uibutton(app.GridLayout8, 'push');
            app.panel01_ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @panel01_btnConnectPushed, true);
            app.panel01_ConnectButton.Text = 'Connect';
            app.panel01_instrConnStat = uilamp(app.GridLayout8);
            app.panel01_instrConnStat.Enable = 'off';
            app.LaserOutputLampLabel = uilabel(app.GridLayout8);
            app.LaserOutputLampLabel.Text = 'Laser Output';
            app.panel01_LaserOutputLamp = uilamp(app.GridLayout8);
            app.panel01_LaserOutputLamp.Enable = 'off';
            app.LaserControlPanel_2 = uipanel(app.GridLayout13);
            app.LaserControlPanel_2.Title = 'Laser Control';
            app.GridLayout9 = uigridlayout(app.LaserControlPanel_2);
            app.WavelengthSpinnerLabel = uilabel(app.GridLayout9);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.panel01_txtWavelength = uispinner(app.GridLayout9);
            app.panel01_txtWavelength.Step = 0.1;
            app.panel01_txtWavelength.Limits = [1500 1640];
            app.panel01_txtWavelength.ValueDisplayFormat = '%.3f';
            app.panel01_txtWavelength.ValueChangedFcn = createCallbackFcn(app, @panel01_txtWavelengthValueChanged, true);
            app.panel01_txtWavelength.Value = 1550;
            app.OutputPowerSpinnerLabel = uilabel(app.GridLayout9);
            app.OutputPowerSpinnerLabel.Text = 'Output Power';
            app.panel01_txtPower = uispinner(app.GridLayout9);
            app.panel01_txtPower.Step = 0.1;
            app.panel01_txtPower.Limits = [0 13];
            app.panel01_txtPower.ValueDisplayFormat = '%5.2f';
            app.panel01_txtPower.ValueChangedFcn = createCallbackFcn(app, @panel01_txtPowerValueChanged, true);
            app.panel01_txtPower.Value = 3;
            app.nmLabel = uilabel(app.GridLayout9);
            app.nmLabel.Text = 'nm';
            app.dBmLabel = uilabel(app.GridLayout9);
            app.dBmLabel.Text = 'dBm';
            app.panel01_btnLaserOn = uibutton(app.GridLayout9, 'state');
            app.panel01_btnLaserOn.ValueChangedFcn = createCallbackFcn(app, @panel01_btnLaserOnPushed, true);
            app.panel01_btnLaserOn.Enable = 'off';
            app.panel01_btnLaserOn.Text = 'On';
            app.PowerSensorPanel = uipanel(app.GridLayout13);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.GridLayout10 = uigridlayout(app.PowerSensorPanel);
            app.AveragingTSpinnerLabel = uilabel(app.GridLayout10);
            app.AveragingTSpinnerLabel.Text = 'Averaging T';
            app.panel01_AveragingTSpinner = uispinner(app.GridLayout10);
            app.panel01_AveragingTSpinner.Limits = [1 1000];
            app.panel01_AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.panel01_AveragingTSpinner.Value = 200;
            app.RangeDropDownLabel = uilabel(app.GridLayout10);
            app.RangeDropDownLabel.Enable = 'off';
            app.RangeDropDownLabel.Text = 'Range';
            app.panel01_RangeDropDown = uidropdown(app.GridLayout10);
            app.panel01_RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.panel01_RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.panel01_RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @panel01_RangeDropDownValueChanged, true);
            app.panel01_RangeDropDown.Enable = 'off';
            app.panel01_RangeDropDown.Value = '-20DBM';
            app.pwrMeas1 = uilabel(app.GridLayout10);
            app.pwrMeas1.Text = '0.00';
            app.msLabel = uilabel(app.GridLayout10);
            app.msLabel.Text = 'ms';
            app.panel01_btnPowerScan = uibutton(app.GridLayout10, 'state');
            app.panel01_btnPowerScan.ValueChangedFcn = createCallbackFcn(app, @panel01_btnPowerScanPushed, true);
            app.panel01_btnPowerScan.Enable = 'off';
            app.panel01_btnPowerScan.Text = 'Run';
            app.panel01_cbAutoRange = uicheckbox(app.GridLayout10);
            app.panel01_cbAutoRange.ValueChangedFcn = createCallbackFcn(app, @panel01_cbAutoRangeValueChanged, true);
            app.panel01_cbAutoRange.Text = 'Auto';
            app.panel01_cbAutoRange.Value = true;
            app.dBmLabel_3 = uilabel(app.GridLayout10);
            app.dBmLabel_3.Text = 'dBm';
            app.OpticalSwitchPanel_2 = uipanel(app.GridLayout13);
            app.OpticalSwitchPanel_2.Title = 'Optical Switch';
            app.GridLayout11 = uigridlayout(app.OpticalSwitchPanel_2);
            app.panel01_swAKnob = uiknob(app.GridLayout11, 'discrete');
            app.panel01_swAKnob.Items = {'1', '2', '3', '4'};
            app.panel01_swAKnob.ValueChangedFcn = createCallbackFcn(app, @panel01_swValueChanged, true);
            app.panel01_swAKnob.Enable = 'off';
            app.panel01_swAKnob.Value = '1';
            app.LMS_2Tab = uitab(app.TabGroup2);
            app.LMS_2Tab.Title = 'LMS_2';
            app.GridLayout14 = uigridlayout(app.LMS_2Tab);
            app.ConnectionPanel_2 = uipanel(app.GridLayout14);
            app.ConnectionPanel_2.Title = 'Connection';
            app.GridLayout8_2 = uigridlayout(app.ConnectionPanel_2);
            app.InstrumentDropDownLabel_2 = uilabel(app.GridLayout8_2);
            app.InstrumentDropDownLabel_2.Text = 'Instrument';
            app.panel01_InstrSelect1_2 = uidropdown(app.GridLayout8_2);
            app.panel01_InstrSelect1_2.ItemsData = {'1001'};
            app.panel01_InstrSelect1_2.Value = '1001';
            app.panel01_ConnectButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.panel01_ConnectButton_2.Text = 'Connect';
            app.panel01_instrConnStat_2 = uilamp(app.GridLayout8_2);
            app.panel01_instrConnStat_2.Enable = 'off';
            app.LaserOutputLampLabel_2 = uilabel(app.GridLayout8_2);
            app.LaserOutputLampLabel_2.Text = 'Laser Output';
            app.panel01_LaserOutputLamp_2 = uilamp(app.GridLayout8_2);
            app.panel01_LaserOutputLamp_2.Enable = 'off';
            app.LaserControlPanel_3 = uipanel(app.GridLayout14);
            app.LaserControlPanel_3.Title = 'Laser Control';
            app.GridLayout9_2 = uigridlayout(app.LaserControlPanel_3);
            app.WavelengthSpinnerLabel_2 = uilabel(app.GridLayout9_2);
            app.WavelengthSpinnerLabel_2.Text = 'Wavelength';
            app.panel01_txtWavelength_2 = uispinner(app.GridLayout9_2);
            app.panel01_txtWavelength_2.Step = 0.1;
            app.panel01_txtWavelength_2.Limits = [1500 1640];
            app.panel01_txtWavelength_2.ValueDisplayFormat = '%.3f';
            app.panel01_txtWavelength_2.Value = 1550;
            app.OutputPowerSpinnerLabel_2 = uilabel(app.GridLayout9_2);
            app.OutputPowerSpinnerLabel_2.Text = 'Output Power';
            app.panel01_txtPower_2 = uispinner(app.GridLayout9_2);
            app.panel01_txtPower_2.Step = 0.1;
            app.panel01_txtPower_2.Limits = [0 13];
            app.panel01_txtPower_2.ValueDisplayFormat = '%5.2f';
            app.panel01_txtPower_2.Value = 3;
            app.nmLabel_11 = uilabel(app.GridLayout9_2);
            app.nmLabel_11.Text = 'nm';
            app.dBmLabel_6 = uilabel(app.GridLayout9_2);
            app.dBmLabel_6.Text = 'dBm';
            app.panel01_btnLaserOn_2 = uibutton(app.GridLayout9_2, 'state');
            app.panel01_btnLaserOn_2.Enable = 'off';
            app.panel01_btnLaserOn_2.Text = 'On';
            app.PowerSensorPanel_2 = uipanel(app.GridLayout14);
            app.PowerSensorPanel_2.Title = 'Power Sensor';
            app.GridLayout10_2 = uigridlayout(app.PowerSensorPanel_2);
            app.AveragingTSpinnerLabel_2 = uilabel(app.GridLayout10_2);
            app.AveragingTSpinnerLabel_2.Text = 'Averaging T';
            app.panel01_AveragingTSpinner_2 = uispinner(app.GridLayout10_2);
            app.panel01_AveragingTSpinner_2.Limits = [1 1000];
            app.panel01_AveragingTSpinner_2.ValueDisplayFormat = '%5.2f';
            app.panel01_AveragingTSpinner_2.Value = 200;
            app.RangeDropDownLabel_2 = uilabel(app.GridLayout10_2);
            app.RangeDropDownLabel_2.Enable = 'off';
            app.RangeDropDownLabel_2.Text = 'Range';
            app.panel01_RangeDropDown_2 = uidropdown(app.GridLayout10_2);
            app.panel01_RangeDropDown_2.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.panel01_RangeDropDown_2.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.panel01_RangeDropDown_2.Enable = 'off';
            app.panel01_RangeDropDown_2.Value = '-20DBM';
            app.pwrMeas1_2 = uilabel(app.GridLayout10_2);
            app.pwrMeas1_2.Text = '0.00';
            app.msLabel_2 = uilabel(app.GridLayout10_2);
            app.msLabel_2.Text = 'ms';
            app.panel01_btnPowerScan_2 = uibutton(app.GridLayout10_2, 'state');
            app.panel01_btnPowerScan_2.Enable = 'off';
            app.panel01_btnPowerScan_2.Text = 'Run';
            app.panel01_cbAutoRange_2 = uicheckbox(app.GridLayout10_2);
            app.panel01_cbAutoRange_2.Text = 'Auto';
            app.panel01_cbAutoRange_2.Value = true;
            app.dBmLabel_7 = uilabel(app.GridLayout10_2);
            app.dBmLabel_7.Text = 'dBm';
            app.OpticalSwitchPanel_3 = uipanel(app.GridLayout14);
            app.OpticalSwitchPanel_3.Title = 'Optical Switch';
            app.GridLayout11_2 = uigridlayout(app.OpticalSwitchPanel_3);
            app.panel01_swAKnob_2 = uiknob(app.GridLayout11_2, 'discrete');
            app.panel01_swAKnob_2.Items = {'1', '2', '3', '4'};
            app.panel01_swAKnob_2.Enable = 'off';
            app.panel01_swAKnob_2.Value = '1';
            app.LightwaveMeasurementSystem02Label = uilabel(app.GridLayout14);
            app.LightwaveMeasurementSystem02Label.Text = 'Lightwave Measurement System 02';
            app.SweepTab = uitab(app.TabGroup2);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout17 = uigridlayout(app.SweepTab);
            app.SweepParametersLabel = uilabel(app.GridLayout17);
            app.SweepParametersLabel.Text = 'Sweep Parameters';
            app.ScanParametersPanel = uipanel(app.GridLayout17);
            app.ScanParametersPanel.Title = 'Scan Parameters';
            app.GridLayout18 = uigridlayout(app.ScanParametersPanel);
            app.ScanStartSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.GridLayout18);
            app.spinStart.Limits = [1500 1640];
            app.spinStart.Value = 1520;
            app.ScanStopSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.GridLayout18);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1580;
            app.StepSizeSpinnerLabel = uilabel(app.GridLayout18);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.GridLayout18);
            app.spinStep.Step = 0.01;
            app.spinStep.Limits = [0.01 1];
            app.spinStep.Value = 0.1;
            app.ScanSpeedLabel = uilabel(app.GridLayout18);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uispinner(app.GridLayout18);
            app.spinSpeed.Step = 0.1;
            app.spinSpeed.Limits = [0.1 10];
            app.spinSpeed.ValueDisplayFormat = '%2.1f';
            app.spinSpeed.Value = 1;
            app.RangeLabel = uilabel(app.GridLayout18);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown_Sweep = uidropdown(app.GridLayout18);
            app.RangeDropDown_Sweep.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown_Sweep.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.RangeDropDown_Sweep.Value = '-10';
            app.nmLabel_2 = uilabel(app.GridLayout18);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.GridLayout18);
            app.nmLabel_3.Text = 'nm';
            app.nmLabel_4 = uilabel(app.GridLayout18);
            app.nmLabel_4.Text = 'nm';
            app.nmsLabel = uilabel(app.GridLayout18);
            app.nmsLabel.Text = 'nm/s';
            app.DataAnalysisTab_2 = uitab(app.TabGroup2);
            app.DataAnalysisTab_2.Title = 'Data Analysis';
            app.GridLayout16 = uigridlayout(app.DataAnalysisTab_2);
            app.Peakfinder1RedPanel = uipanel(app.GridLayout16);
            app.Peakfinder1RedPanel.Title = 'Peak finder 1 (Red)';
            app.GridLayout7 = uigridlayout(app.Peakfinder1RedPanel);
            app.HeightLabel = uilabel(app.GridLayout7);
            app.HeightLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout7);
            app.HeightSpinner.Value = -35;
            app.ProminenceLabel = uilabel(app.GridLayout7);
            app.ProminenceLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.GridLayout7);
            app.ProminenceSpinner.Value = 15;
            app.MaxWidthLabel = uilabel(app.GridLayout7);
            app.MaxWidthLabel.Text = 'Max Width';
            app.MaxWidthSpinner = uispinner(app.GridLayout7);
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthLabel = uilabel(app.GridLayout7);
            app.MinWidthLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.GridLayout7);
            app.PeakSpacingLabel = uilabel(app.GridLayout7);
            app.PeakSpacingLabel.Text = 'Peak Spacing';
            app.DistanceSpinner = uispinner(app.GridLayout7);
            app.DistanceSpinner.Value = 3;
            app.dBmLabel_4 = uilabel(app.GridLayout7);
            app.dBmLabel_4.Text = 'dBm';
            app.nmLabel_5 = uilabel(app.GridLayout7);
            app.nmLabel_5.Text = 'nm';
            app.nmLabel_6 = uilabel(app.GridLayout7);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.GridLayout7);
            app.nmLabel_7.Text = 'nm';
            app.MinPeakHeightCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakHeightCheckBox.Text = '';
            app.MinPeakProminenceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakProminenceCheckBox.Text = '';
            app.MinPeakProminenceCheckBox.Value = true;
            app.MaxPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MaxPeakWidthCheckBox.Text = '';
            app.MinPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakWidthCheckBox.Text = '';
            app.MinPeakDistanceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakDistanceCheckBox.Text = '';
            app.InterferenceAnalysisPanel = uipanel(app.GridLayout16);
            app.InterferenceAnalysisPanel.Title = 'Interference Analysis';
            app.GridLayout3 = uigridlayout(app.InterferenceAnalysisPanel);
            app.LnmLabel = uilabel(app.GridLayout3);
            app.LnmLabel.Text = 'L (nm)';
            app.txtResL = uieditfield(app.GridLayout3, 'numeric');
            app.txtResL.Value = 62831;
            app.TypeofdeviceDropDownLabel = uilabel(app.GridLayout3);
            app.TypeofdeviceDropDownLabel.Text = 'Type of device';
            app.optTOD = uidropdown(app.GridLayout3);
            app.optTOD.Items = {'Resonator', 'Interferometer', 'Other'};
            app.optTOD.Value = 'Resonator';
            app.neffectiveEditFieldLabel = uilabel(app.GridLayout3);
            app.neffectiveEditFieldLabel.Text = 'n effective ';
            app.txtneff = uieditfield(app.GridLayout3, 'numeric');
            app.txtneff.Limits = [1 50];
            app.txtneff.Value = 3.4;
            app.DataAnalysisParametersLabel = uilabel(app.GridLayout16);
            app.DataAnalysisParametersLabel.Text = 'Data Analysis Parameters';
            app.ConfigTab = uitab(app.TabGroup2);
            app.ConfigTab.Title = 'Config';
            app.tblConfig = uitable(app.ConfigTab);
            app.tblConfig.ColumnName = {'ID'; 'Name'; 'Type'; 'Description'; 'Vendor'; 'Board'; 'GPIB Port'; 'Timeout'; 'Enabled'};
            app.tblConfig.RowName = {};
            app.tblConfig.ColumnEditable = true;
            app.SaveButton = uibutton(app.ConfigTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @tools_btnSaveConfigPushed, true);
            app.SaveButton.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.RightPanel);
            app.UIAxes01 = uiaxes(app.GridLayout12);
            title(app.UIAxes01, 'Spectrum')
            xlabel(app.UIAxes01, 'wavelength (m)')
            ylabel(app.UIAxes01, 'intensity (dBm)')
            app.logger = uitextarea(app.GridLayout12);
            app.logger.Value = {'Spectral Measurements 1.1.2  (C) NYU PRL 2019'};
            app.btnRunSweep = uibutton(app.GridLayout12, 'state');
            app.btnRunSweep.ValueChangedFcn = createCallbackFcn(app, @btnRunSweepPushed, true);
            app.btnRunSweep.Enable = 'off';
            app.btnRunSweep.Text = 'Run Sweep';
            app.tblResonanceAnalysis = uitable(app.GridLayout12);
            app.tblResonanceAnalysis.ColumnName = {'Peak'; 'Wavelength'; 'FSR'; 'FWHM'; 'Q. Factor'; 'Group Index'; 'Loss Coeficient'};
            app.tblResonanceAnalysis.RowName = {};
            app.FindPeaksButton = uibutton(app.GridLayout12, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @btnFindPeakPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.DisplayPeaksCheckBox = uicheckbox(app.GridLayout12);
            app.DisplayPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayPeaksCheckBoxValueChanged, true);
            app.DisplayPeaksCheckBox.Text = 'Display Peaks';
            app.Peakfinder2GreenPanel = uipanel(app.SpectralMeasurement);
            app.Peakfinder2GreenPanel.Title = 'Peak finder 2 (Green)';
            app.GridLayout6 = uigridlayout(app.Peakfinder2GreenPanel);
            app.HeightSpinner_2Label = uilabel(app.GridLayout6);
            app.HeightSpinner_2Label.Text = 'Height';
            app.HeightSpinner_2 = uispinner(app.GridLayout6);
            app.HeightSpinner_2.Value = -35;
            app.ProminenceSpinner_2Label = uilabel(app.GridLayout6);
            app.ProminenceSpinner_2Label.Text = 'Prominence';
            app.ProminenceSpinner_2 = uispinner(app.GridLayout6);
            app.ProminenceSpinner_2.Value = 15;
            app.MaxWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MaxWidthSpinner_2Label.Text = 'Max Width';
            app.MaxWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MinWidthSpinner_2Label.Text = 'Min Width';
            app.MinWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2.Value = 0.5;
            app.PeakSpacingLabel_2 = uilabel(app.GridLayout6);
            app.PeakSpacingLabel_2.Text = 'Peak Spacing';
            app.DistanceSpinner_2 = uispinner(app.GridLayout6);
            app.DistanceSpinner_2.Value = 3;
            app.dBmLabel_5 = uilabel(app.GridLayout6);
            app.dBmLabel_5.Text = 'dBm';
            app.nmLabel_8 = uilabel(app.GridLayout6);
            app.nmLabel_8.Text = 'nm';
            app.nmLabel_9 = uilabel(app.GridLayout6);
            app.nmLabel_9.Text = 'nm';
            app.nmLabel_10 = uilabel(app.GridLayout6);
            app.nmLabel_10.Text = 'nm';
            app.MinPeakHeightCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakHeightCheckBox_2.Text = '';
            app.MinPeakProminenceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakProminenceCheckBox_2.Text = '';
            app.MaxPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MaxPeakWidthCheckBox_2.Text = '';
            app.MinPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakWidthCheckBox_2.Text = '';
            app.MinPeakDistanceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakDistanceCheckBox_2.Text = '';
            % Show the figure after all components are created
            app.SpectralMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralMeasurements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralMeasurement)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralMeasurement)
        end
    end
end